classdef Psitrum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QCSappUIFigure             matlab.ui.Figure
        DiagramsButton             matlab.ui.control.Button
        DensityButton              matlab.ui.control.Button
        DensityTracerTabGroup      matlab.ui.container.TabGroup
        DensityTab                 matlab.ui.container.Tab
        UIAxes2                    matlab.ui.control.UIAxes
        TablesButton               matlab.ui.control.Button
        BlochButton                matlab.ui.control.Button
        TabGroupTables             matlab.ui.container.TabGroup
        InputVectorTab             matlab.ui.container.Tab
        InputVector                matlab.ui.control.Table
        NoiseParametersTab         matlab.ui.container.Tab
        NoiseParameters            matlab.ui.control.Table
        StringAlgorithmMatrixTab   matlab.ui.container.Tab
        StringAlgorithmMatrix      matlab.ui.control.Table
        ComplexAlgorithmMatrixTab  matlab.ui.container.Tab
        ComplexAlgorithmMatrix     matlab.ui.control.Table
        OutputVectorTab            matlab.ui.container.Tab
        OutputVectorT              matlab.ui.control.Table
        DensityMatrixTab           matlab.ui.container.Tab
        DensityMatrix              matlab.ui.control.Table
        NoisyDensityMatrixTab      matlab.ui.container.Tab
        NoisyDensityMatrix         matlab.ui.control.Table
        BlochTracerTabGroup        matlab.ui.container.TabGroup
        BlochsTab                  matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        TabGroupVisuals            matlab.ui.container.TabGroup
        ProbDiagramTab             matlab.ui.container.Tab
        ProbDiagram                matlab.ui.control.UIAxes
        AlgorithmMatrixHeatmapTab  matlab.ui.container.Tab
        AlgorithmMatrix3DTab       matlab.ui.container.Tab
        AlgorithmMatrix3D          matlab.ui.control.UIAxes
        HTML                       matlab.ui.control.HTML
    end

    
    %% Not defualt app designer properties (Programmer Defined)
    properties (Access = private)
    %----------------------------------------------------------------------------------------------------------------------------------------------------------------  
        NumOfQubits         % Number of Qubits
        NumOfStages         % Number of Stages
        densityTabs         % Array of Tab objects containing density matrix diagrams (parent: grouptab)
        densityGraphs       % Array of density matrix objects (parent: densityTabs)
        densityColorBars    % Arrays containing the color bar object for each density matrix
        densityNoisyGraphs  % Array of noisy desnity matrix objects (parent: densityTabs)
        blochVectors        % Array of bloch sphere vectors 
        blochTabs           % Array of Tab objects containing bloch sphere (parent: grouptab)
        blochs              % Array of bloch spheres objects (parent: blochTabs)
        StageKnobs          % Array of Knob objects that trace for each stage (parent: blochTabs)
        ClearKnob           % Array of Knob objects that clear settings for each qubit bloch sphere (parent: blochTabs)
        DensityKnob         % Array of Knob objects that trace density matrix (parent: densityTabs)
        DensityMatrixHeatmap        % Density Matrix Heatmap Diagram Object
        AlgorithmMatrixHeatmap      % Algorithm Numeric Matrix Heatmap Diagram Object
        QAlgorithm                  % String Matrix for Quantum Algorithm/circuit
    %----------------------------------------------------------------------------------------------------------------------------------------------------------------  
    end
    
    %% Not defualt app designer methods (Programmer Defined)
    methods (Access = public)
        
        %% App Main simulation function
        % This function starts all the functions of the simualtor if the data from the HTML component comes to MATLAB
        function simulate(app)
        %----------------------------------------------------------------------------------------------------------------------------------------------
            dataTable= transpose(table2array(struct2table(jsondecode(app.HTML.Data))));   % Taking data from HTMLComponent and reformat it into table
            app.QAlgorithm = string(dataTable);                 % Extracting the algorithm Matrix from Table Object
            NoiseParametersArray= app.QAlgorithm(1,:)';         % Extracting the first row which contains noise parameters from the algorithm 
            app.QAlgorithm(1,:)= [];                            % Deleting the row of parameters from algorithm (no need for it) 
            QubitsToBeMeasuredArray= app.QAlgorithm(:,end)';    % Extracting the last column which contains measurement information
            QubitsInitialStateArray= app.QAlgorithm(:,1)';      % Extracting the first column which contains Initialization information
            app.QAlgorithm(:,end)= [];                          % Deleting last column from algorithm matrix (no need for it) 
            app.QAlgorithm(:,1)= [];                            % Deleting first column from algorithm matrix (no need for it)
            NoiseType= NoiseParametersArray(1);                 % Extracting the type of noise from noise parameters array
            NoiseParametersArray(1)= [];                        % == and deleting its cell (no need for it)
            NoiseMethod= NoiseParametersArray(1);               % Extracting the method of the noise from noise parameters array
            NoiseParametersArray(1)= [];                        % == and deleting its cell (no need for it)
            NoiseParametersArray= str2double(NoiseParametersArray);     % Change the format of the noise parameters into double
            app.NumOfStages = size(app.QAlgorithm,2);               % Calculating Number of Stages
            app.NumOfQubits = size(app.QAlgorithm,1);               % Calculating Number of Qubits
            MeasuredQubits = getQubitsToBeMeasuredNumeric(app,QubitsToBeMeasuredArray);         % Vector of Qubits to be Measured
            MeasuredQubitsSize = size(MeasuredQubits,2);                                        % Nuber of qubits to be measured
            % Preparing the Initial state by taking the value from the field specified
            InitialQState = getInitialStateNumeric(app, QubitsInitialStateArray);
            % Randomizing the parameters if the method chosen is Stochastic
            if NoiseMethod== "Stoc"
                for i= 1:size(NoiseParametersArray)
                    P = NoiseParametersArray(i)*rand(1);
                    NoiseParametersArray(i) = P*4^app.NumOfQubits/(4^app.NumOfQubits-1);               %   0 ≤ NoisePar ≤ 4^n/(4^n−1) , probability for an error
                end
            end
            %% Applying the main function QCS 
            [OutputVector, OutputNoisyVector, OutputVectorPropability, OutputNoisyVectorPropability, OutputMatrix, OutputRhoMatrix, OutputNoisyRhoMatrix, StageTraceVector, StageTraceNoisyVector] = PsitrumMainFunction(app.QAlgorithm, MeasuredQubits, InitialQState, NoiseType, NoiseParametersArray);
            plotProbability(app,OutputVectorPropability, OutputNoisyVectorPropability, MeasuredQubitsSize);    % plotting the probability diagram
            computeBlochVectors(app, InitialQState, full(StageTraceVector))         % Computing the bloch sphere vectors 
            % plotting: Input vector, algorithm string matrix, algorithm numeric matrix, and output vector
            plotInputAlgorithmOutputDensity(app,InitialQState,app.QAlgorithm,OutputMatrix,OutputVector,OutputNoisyVector, OutputRhoMatrix, OutputNoisyRhoMatrix, NoiseParametersArray);
            plotBlochSpheres(app);  % plotting the bloch vectors for each qubit
            plotDensityMatrices(app, full(StageTraceVector),full(StageTraceNoisyVector));       % plotting the density matrix for each stage
        %----------------------------------------------------------------------------------------------------------------------------------------------
        end
        
        %This function used to find the index of qubits to be measured
        function MQubits= getQubitsToBeMeasuredNumeric(app, QubitsStringVector)
        %---------------------------------------------------------------------------------------
            count=1;
            for i=1:app.NumOfQubits
               if QubitsStringVector(i) == "MY"         % case: yes measure the qubit
                   MQubits(count)= i;                   % accumalating the qubits to be measured
                   count = count+1;
               end  
            end
        %---------------------------------------------------------------------------------------
        end
        
        %This function used to find the initial state of the qubits
        function InitialNumeric= getInitialStateNumeric(app, InitialStringArray)
        %------------------------------------------------------------------------------------
            temp= "";
            for i=1:app.NumOfQubits
               if InitialStringArray(i) == "One"        %case: qubit initial value is one
                   temp = temp + "1";
               else                                     %case: qubit initial value is zero
                   temp = temp + "0";
               end  
            end
            InitialNumeric= InitializeInt2Qstates(app.NumOfQubits, bin2dec(temp));
        %------------------------------------------------------------------------------------
        end
        
        % plotting the probability diagram
        function plotProbability(app, OutputVectorPropability, OutputNoisyVectorPropability, MeasuredQubitsSize)
        %----------------------------------------------------------------------------------------------------------------------------------------------------------------------
            %% Plotting Main Probability Diagram
            cla(app.ProbDiagram, 'reset');   % clear the previous diagram if any
            % plotting catogorical bars for each state according to its probability percentage
            bar(app.ProbDiagram,categorical(cellstr(dec2bin(0:2^MeasuredQubitsSize-1))),100*OutputVectorPropability,'faceColor','b');
            % plotting the name percentage and state value in a text format 
            text(app.ProbDiagram, 1:2^MeasuredQubitsSize, 100*OutputVectorPropability, num2str(100*OutputVectorPropability,'%0.1f %%'),'HorizontalAlignment','center','VerticalAlignment','bottom');
            ylim(app.ProbDiagram,[0 100]);                                      % percentage limits for y axes
            xlabel(app.ProbDiagram,'Quantum states');                           % x axes name
            ylabel(app.ProbDiagram,'Measurement probability (%)');              % y axes name
            title(app.ProbDiagram,'Quantum states measurement distribution');   % title of the diagram
            grid(app.ProbDiagram,"on");                                         % change the diagram format into grid layout

            %% Plotting Noisy Probability Diagram
%             cla(app.NoisyProbDiagram, 'reset');   % clear the previous diagram if any
            % plotting catogorical bars for each state according to its probability percentage
%             bar(app.NoisyProbDiagram,categorical(cellstr(dec2bin(0:2^MeasuredQubitsSize-1))),100*OutputNoisyVectorPropability,'faceColor','b');
            % plotting the name percentage and state value in a text format 
%             text(app.NoisyProbDiagram, 1:2^MeasuredQubitsSize, 100*OutputNoisyVectorPropability, num2str(100*OutputNoisyVectorPropability,'%0.1f %%'),'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylim(app.NoisyProbDiagram,[0 100]);                                      % percentage limits for y axes
%             xlabel(app.NoisyProbDiagram,'Quantum states');                           % x axes name
%             ylabel(app.NoisyProbDiagram,'Measurement probability (%)');              % y axes name
%             title(app.NoisyProbDiagram,'Quantum states measurement distribution');   % title of the diagram
%             grid(app.NoisyProbDiagram,"on");                                         % change the diagram format into grid layout
%             exportgraphics(app.NoisyProbDiagram,'results/NoisyProbabilityDiagram.png',
%             'BackgroundColor', 'white', 'Resolution',300); % exporting diagram into png file 
        %----------------------------------------------------------------------------------------------------------------------------------------------------------------------
        end
        
        % plotting: Input vector, algorithm string matrix, algorithm numeric matrix, and output vector
        function plotInputAlgorithmOutputDensity(app, Input, StringMatrix, ComplexMatrix, Output, OutputNoisyVector, DensityMatrix, OutputRhoMatrix, NoiseParametersArray)
        %-------------------------------------------------------------------------------------------------------------------------------------
            size=  pow2(app.NumOfQubits);       % size of the quantum states vectors
            %------------------------------------------------------
            %% Creating the columns and rows titles for the tables
            %------------------------------------------------------
            RowsValues = dec2bin(0:1:2^app.NumOfQubits-1)-'0';   % tables rows names (binary representaion of the states) 
            s="{";              % temporary statement (string) variable for creating struct of rows names (e.g. {'00','01','10','11'})
            q="{";              % temporary statement (string) variable for creating struct of qubit names (e.g. {'Q0','Q1','Q2'})
            st="{";             % temporary statement (string) variable for creating struct of qubit names (e.g. {'St0','St1','St2'})
            for i= 1:size
                s=s+"'";
                for j= 1:app.NumOfQubits
                    s=s+""+RowsValues(i,j);
                   if i==1
                       q= q+"'q"+(j-1)+ "'";
                        if i ~= size
                            q=q + ",";
                        end
                   end
                end
                s= s+"'";
                if i ~= size
                    s=s + ",";
                end
            end
            for i= 0:(app.NumOfStages-1)
                st= st+"'S"+(i)+ "'";
                if i ~= (app.NumOfStages-1)
                    st=st + ",";
                end
            end
            s=s + "}";
            q=q + "}";
            st=st + "}";
            ss= eval(s);        % evalute the command contained in s which is a struct
            qq= eval(q);        % evalute the command contained in q which is a struct
            stt= eval(st);      % evalute the command contained in st which is a struct
            InOutColsNames= {'Amplitude', 'Probability (%)'};   % Columns of Input and Output Vector tables
            %--------------------------------------------------------------
            %% Assigning the vectors and matrices to their specific tables
            %--------------------------------------------------------------
            Input= [Input (abs(Input).^2)*100];             %Computing the propability percentage of the input state
            app.InputVector.Data= Input;                    %Assigning Input state vector to its table 
            app.InputVector.RowName= ss;                    %and its rows (e.g. {'00','01','10','11'})
            app.InputVector.ColumnName= InOutColsNames;     %and its columns (e.g. {'00','01','10','11'})
            app.StringAlgorithmMatrix.Data= StringMatrix;   %Assigning String algorithm matrix to its table
            app.StringAlgorithmMatrix.RowName= qq;          %and its rows (e.g. {'q0','q1','q2'})
            app.StringAlgorithmMatrix.ColumnName= stt;      %and its columns (e.g. {'S0','S1','S2'})
            app.ComplexAlgorithmMatrix.Data= ComplexMatrix; %Assigning Numeric (Complex) algorithm matrix to its table
            app.ComplexAlgorithmMatrix.RowName= ss;         %and its rows (e.g. {'00','01','10','11'})
            app.ComplexAlgorithmMatrix.ColumnName= ss;      %and its columns (e.g. {'00','01','10','11'})
            app.NoiseParameters.Data= NoiseParametersArray;
            app.NoiseParameters.RowName= stt;

            Output= [Output abs(Output).^2];         %Computing the propability percentage of the input state
            % OutputNoisyVector= [OutputNoisyVector abs(OutputNoisyVector).^2];         %Computing the propability percentage of the input state
            app.OutputVectorT.Data= Output;                  %Assigning Output state vector to its table 
            app.OutputVectorT.RowName= ss;                   %and its rows (e.g. {'00','01','10','11'})
            app.OutputVectorT.ColumnName= InOutColsNames;    %and its columns (e.g. {'00','01','10','11'})
            % app.OutputNoisyVectorT.Data= OutputNoisyVector;                  %Assigning Output state vector to its table 
            % app.OutputNoisyVectorT.RowName= ss;                   %and its rows (e.g. {'00','01','10','11'})
            % app.OutputNoisyVectorT.ColumnName= InOutColsNames;    %and its columns (e.g. {'00','01','10','11'})
            app.DensityMatrix.Data= DensityMatrix;          %Assigning Density Matrix to its table 
            app.DensityMatrix.RowName= ss;                  %and its rows (e.g. {'00','01','10','11'})
            app.DensityMatrix.ColumnName= ss;               %and its columns (e.g. {'00','01','10','11'})
            app.NoisyDensityMatrix.Data= OutputRhoMatrix;          %Assigning Density Matrix to its table 
            app.NoisyDensityMatrix.RowName= ss;                  %and its rows (e.g. {'00','01','10','11'})
            app.NoisyDensityMatrix.ColumnName= ss;               %and its columns (e.g. {'00','01','10','11'})
            % Plotting the heatmap diagram for the density matrix then algorithm numeric matrix
            app.AlgorithmMatrixHeatmap= heatmap(app.AlgorithmMatrixHeatmapTab, abs(app.ComplexAlgorithmMatrix.Data));
            title(app.AlgorithmMatrixHeatmap, 'Algorithm Matrix Heatmap');
            y= bar3(app.AlgorithmMatrix3D, abs(app.ComplexAlgorithmMatrix.Data));
            title(app.AlgorithmMatrix3D, 'Algorithm Matrix 3D-Map');
            colorbar(app.AlgorithmMatrix3D);
            for k = 1:length(y)
                zdata2 = y(k).ZData;
                y(k).CData = zdata2;
                y(k).FaceColor = 'interp';
            end
        %-------------------------------------------------------------------------------------------------------------------------------------
        end
        
        %computing the bloch vector for each qubit after each stage
        function computeBlochVectors(app,InputState, StageTraceVector)
        %------------------------------------------------------------------------------------------------------------------------------------------------   
            % Initializing the variable that contains the bloch vectors of each qubit in each stage
            app.blochVectors= zeros(3,app.NumOfStages+1, app.NumOfQubits);
            %iterating over stages (+1 for initial stage)
            for i = 1: (app.NumOfStages+1) 
                if i==1                                 % Special case for the initial stage which has fixed value 
                    for j= 1: app.NumOfQubits           % iterating over the qubits
                        if app.NumOfQubits== 1          % special case if there only one qubit because partialTrace() function will not work           
                            % Computing the bloch vector for the qubit by first computing its density matrix
                            app.blochVectors(:,i,j)= computeBlochVector(computeDensityMatrix(InputState)); 
                        else
                            %Calculating the indecies for all the qubits that to be traced out by partialtrace
                            subSys= zeros(1,app.NumOfQubits-1);
                            count=1;
                            for k= 1:app.NumOfQubits
                                if j ~= k
                                    subSys(count)= k;
                                    count= count+1;
                                end  
                            end
                            % Computing the bloch vector for the qubit by first computing its density matrix
                            app.blochVectors(:,i,j)= computeBlochVector(partialTrace(InputState,subSys, 2.* ones(1,app.NumOfQubits)));
                        end
                    end
                else                % Case if the stage is not the initial stage
                    % Computing the current state of the stage by multplying the previous state by the previous State Trace Matrix
                    for j= 1: app.NumOfQubits
                        if app.NumOfQubits== 1          % Special case if there only one qubit because partialTrace() function will not work     
                            % Computing the bloch vector for the qubit by first computing its density matrix
                            app.blochVectors(:,i,j)= computeBlochVector(computeDensityMatrix(StageTraceVector(:,i)));
                        else
                            %Calculating the indecies for all the qubits that to be traced out by partialtrace
                            subSys= zeros(1,app.NumOfQubits-1);
                            count=1;
                            for k=1:app.NumOfQubits
                                if j ~= k
                                    subSys(count)= k;
                                    count= count+1;
                                end  
                            end
                            % Computing the bloch vector for the qubit by first computing its density matrix
                            app.blochVectors(:,i,j)= computeBlochVector(partialTrace(StageTraceVector(:, i),subSys, 2.* ones(1,app.NumOfQubits)));
                        end 
                    end
                end
            end
        %------------------------------------------------------------------------------------------------------------------------------------------------
        end
        
        % plotting the bloch vectors for each qubit 
        function plotDensityMatrices(app, StageTraceVector, StageTraceNoisyVector)
        %----------------------------------------------------------------------------------------------------------------------------------
            if isempty(app.blochs) == 0    % Case if not the first simulation trial. It clear all children of TabGroups
               delete(get(app.DensityTracerTabGroup, 'Children'));      % Delete the contents of the TabGroups object
            else        % Case for the first simulation trial. It clear all defualt bloch objects
               delete(app.densityTabs);          % Delete the contents of the bloch object
            end
            
            for s= 1:(app.NumOfStages+1)        
                DM= computeDensityMatrix(StageTraceVector(:,s));
                DMN= computeDensityMatrix(StageTraceNoisyVector(:,s));
                TabName= "app.densityTabs.D"+s;  
                if s==1
                    TabTitle= "S-I"; 
                    tit= "Density Matrix Initial";
                    titN= "Noisy Density Matrix Initial";
                elseif s== (app.NumOfStages+1)
                    TabTitle= "S-F"; 
                    tit= "Density Matrix Final";
                    titN= "Noisy Density Matrix Final";
                else
                    TabTitle= "S-"+(s-2); 
                    tit= "Density Matrix "+(s-2)+"";
                    titN= "Noisy Density Matrix "+(s-2)+"";
                end       
                statement= ""+TabName+"= uitab(app.DensityTracerTabGroup, 'Title','"+ TabTitle+ "');";
                eval(statement);                
                statement="app.densityGraphs.DG"+s+"= uiaxes("+TabName+");";     
                eval(statement);
                statement="app.densityNoisyGraphs.DG"+s+"= uiaxes("+TabName+");";     
                eval(statement);

                x= bar3(eval("app.densityGraphs.DG"+s), abs(DM));
                y= bar3(eval("app.densityNoisyGraphs.DG"+s), abs(DMN));
                for k = 1:length(x)
                    zdata1 = x(k).ZData;
                    zdata2 = y(k).ZData;
                    x(k).CData = zdata1;
                    y(k).CData = zdata2;
                    x(k).FaceColor = 'interp';
                    y(k).FaceColor = 'interp';
                end

                title(eval("app.densityGraphs.DG"+s), tit); 
                xlabel(eval("app.densityGraphs.DG"+s), 'X');
                ylabel(eval("app.densityGraphs.DG"+s), 'Y');
                zlabel(eval("app.densityGraphs.DG"+s), 'Z');
                statement= "app.densityGraphs.DG"+s+".Position=[25,27,527,436];";
                eval(statement);
                statement= "app.densityColorBars.CB"+s+"= colorbar(app.densityGraphs.DG"+s+")"; 
                eval(statement);

                title(eval("app.densityNoisyGraphs.DG"+s), titN); 
                xlabel(eval("app.densityNoisyGraphs.DG"+s), 'X');
                ylabel(eval("app.densityNoisyGraphs.DG"+s), 'Y');
                zlabel(eval("app.densityNoisyGraphs.DG"+s), 'Z');
                statement= "app.densityNoisyGraphs.DG"+s+".Position=[25,27,527,436];";
                eval(statement);
                statement= "app.densityColorBars.CBN"+s+"=colorbar(app.densityNoisyGraphs.DG"+s+")"; 
                eval(statement);

                set([eval("app.densityNoisyGraphs.DG"+s)],'Visible','off');
                set([eval("app.densityNoisyGraphs.DG"+s+".Children")],'Visible','off');
                set([eval("app.densityColorBars.CBN"+s)],'Visible','off');
                statement2="app.DensityKnob.D"+s+"= uiknob("+TabName+", 'discrete','Position', [33,14,30,30],'Items',{'None', 'Noise'}, 'ItemsData', {'0','1'}, 'ValueChangedFcn', @(txt,event) KnobValueChanged2(app,event,"+s+"));";
                eval(statement2);
            end 
        %----------------------------------------------------------------------------------------------------------------------------------
        end
        
        % plotting the bloch vectors for each qubit 
        function plotBlochSpheres(app)
        %----------------------------------------------------------------------------------------------------------------------------------
            if isempty(app.blochs) == 0    % Case if not the first simulation trial. It clear all children of TabGroups
               delete(get(app.BlochTracerTabGroup, 'Children'));      % Delete the contents of the TabGroups object
            else        % Case for the first simulation trial. It clear all defualt bloch objects
               delete(app.BlochsTab);          % Delete the contents of the bloch object
            end
            
            for q= 0:(app.NumOfQubits-1)        % iterate over the qubits to create bloch spheres
                QubitName= "Qubit "+ q;          % Qubit Name 
                TabName= "app.blochTabs.T"+q;   % Bloch Sphere tab object name
                TabTitle= "q"+q;            % Bloch Sphere tab object title
                statement= ""+TabName+"= uitab(app.BlochTracerTabGroup, 'Title','"+ TabTitle+ "');";
                eval(statement);                % creating new tab object to contain this bloch sphere
                createBlochSphereObject(app, TabName, QubitName, q); % function that create the bloch sphere inside the tab
            end 
        %----------------------------------------------------------------------------------------------------------------------------------
        end
        
        % it creates ui objects for the bloch spheres and plotting their properties
        function createBlochSphereObject(app, TabName, QubitName, QubitNum)
        %--------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            statement="app.blochs.B"+QubitNum+"= uiaxes("+TabName+");";     
            eval(statement);                    % creating bloch vector axeses for plotting the bloch spheres
            %The setting for bloch shpere objects in the diagram
            title(eval("app.blochs.B"+QubitNum), QubitName); 
            xlabel(eval("app.blochs.B"+QubitNum), 'Y');
            ylabel(eval("app.blochs.B"+QubitNum), 'X');
            zlabel(eval("app.blochs.B"+QubitNum), 'Z');
            statement= "app.blochs.B"+QubitNum+".Position=[27 27 527 436];";
            eval(statement);
            [Xx,Yy,Zz]= sphere(25);             %Creating sphere with radius one and 25-by-25 faces
            axis(eval("app.blochs.B"+QubitNum),'equal');
            surf(eval("app.blochs.B"+QubitNum),Xx,Yy,Zz,'FaceAlpha',0.25);
            colormap(eval("app.blochs.B"+QubitNum),'gray');
            %shading(eval("app.blochs.B"+QubitNum),'interp');
            
            %Plotting the lines of the axises x,y,z
            line( eval("app.blochs.B"+QubitNum) , [-1,1], [0,0], [0,0], 'LineWidth', 1.5, 'Color', [0,0,0]);
            line(eval("app.blochs.B"+QubitNum), [0,0], [-1,1], [0,0], 'LineWidth', 1.5, 'Color', [0,0,0]);
            line(eval("app.blochs.B"+QubitNum), [0,0], [0,0], [-1,1], 'LineWidth', 1.5, 'Color', [0,0,0]);
            
            %plotting the labels for axes ends |0>, |1>, |+>, |->
            text(eval("app.blochs.B"+QubitNum) , 0 , 0 , 1.2 , '|0>' , 'FontSize' , 10 , 'HorizontalAlignment' , 'Center' );
            text(eval("app.blochs.B"+QubitNum), 1.2, 0, 0, '|+>', 'FontSize', 10, 'HorizontalAlignment', 'Center');
            text(eval("app.blochs.B"+QubitNum), -1.2, 0, 0, '|->', 'FontSize', 10, 'HorizontalAlignment', 'Center');
            text(eval("app.blochs.B"+QubitNum), 0, 0, -1.2, '|1>', 'FontSize', 10, 'HorizontalAlignment', 'Center');
            view(eval("app.blochs.B"+QubitNum),[45,15]);
            
            %Creating a knob object for each qubit with a callback function KnobValueChanged
            statement2="app.StageKnobs.K"+QubitNum+"= uiknob("+TabName+", 'discrete','Position', [520 20 30 30], 'ValueChangedFcn', @(txt,event) KnobValueChanged(app,event,"+QubitNum+"));";
            eval(statement2);
            
            %Creating a Clear knob object for each qubit to decide wither clearing previou bloch vectors or not
            statement2="app.ClearKnob.K"+QubitNum+"= uiknob("+TabName+", 'discrete','Position', [30 10 30 30]);";
            eval(statement2);
            
            item="{";             % temporary statement (string) variable for creating struct of stage names (e.g. {I,0,1,2,...,F})
            itemD="{";             % temporary statement (string) variable for creating struct of stage names (e.g. {0,1,2,...,n})
            for i= 0:app.NumOfStages
                if i == 0
                    item= item+"'I'";
                elseif i== app.NumOfStages
                    item= item+"'F'";
                else
                    item= item+"'"+(i-1)+ "'";
                end
                itemD= itemD+"'"+(i)+ "'";
                if i ~= app.NumOfStages
                    item=item + ",";
                    itemD=itemD + ",";
                end
            end
            item=item + "}";
            itemD=itemD + "}";
            statement2= "app.StageKnobs.K"+QubitNum+".Items="+item+";";
            eval(statement2);               %Assign the labels for the knob ({I,0,1,2,...,F})
            statement2= "app.StageKnobs.K"+QubitNum+".ItemsData="+itemD+";";
            eval(statement2);               %Assign the labels for the knob ({0,1,2,...,n})
            statement2= "app.ClearKnob.K"+QubitNum+".Items={'One', 'All'};";
            eval(statement2);               %Assign the labels for the Clear knob ({'One', 'All'})
            statement2= "app.ClearKnob.K"+QubitNum+".ItemsData={'0', '1'};";
            eval(statement2);               %Assign the labels for the knob ({'0', '1'})
            %Plotting the Final stage Bloch Vector directly without interaction
            subSys= zeros(1,app.NumOfQubits-1);
            count=1;
            for i=0:(app.NumOfQubits-1)
                if QubitNum ~= i
                    subSys(1,count)= i+1;
                    count= count+1;
                end  
            end
            statement= "app.blochVectors(:, app.NumOfStages+1, "+(QubitNum+1)+")";
            bv= eval(statement);            %Extracting the Bloch Vector from blochVectors array
            line(eval("app.blochs.B"+QubitNum), [0 bv(1)], [0 bv(2)], [0 bv(3)],'LineWidth', 2, 'Marker', 'o', 'Color', '#a00000');
            statement= "app.StageKnobs.K"+QubitNum+".Value='"+app.NumOfStages+"';";
            eval(statement);            %Change the current value of the knob to F
            %--------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
        end
        
        %This function cleas all the lines in a diagram (axes)
        function ClearLinesFromAxes(app, axesToClear)
        %---------------------------------------------------------------------
            axesHandlesToChildObjects = findobj(axesToClear, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end  
             return; % from ClearLinesFromAxes
        %---------------------------------------------------------------------
        end
    end
    
    % Not defualt app designer methods (our private methods)
    methods (Access = private)
        %Callback function excuted when the user moves the handle of the knob to certain value
        function KnobValueChanged(app, event, QubitNum)
        %---------------------------------------------------------------------------------------------------------------------
            statement= "app.ClearKnob.K"+QubitNum+".Value;";
            flag= eval(statement);
            if( flag == '0')
                ClearLinesFromAxes(app, eval("app.blochs.B"+QubitNum));     
            end
            statement= "app.blochVectors(:, str2num(app.StageKnobs.K"+QubitNum+".Value)+1, "+(QubitNum+1)+")";
            bv= eval(statement);            %Extracting the Bloch Vector from blochVectors array
            %% Plotting the Bloch vector for chosen stage in the diagram
            line(eval("app.blochs.B"+QubitNum), [0 bv(1)], [0 bv(2)], [0 bv(3)],'LineWidth', 2, 'Marker', 'o', 'Color', '#a00000');
            line(eval("app.blochs.B"+QubitNum) , [-1,1], [0,0], [0,0], 'LineWidth', 1.5, 'Color', [0,0,0]);
            line(eval("app.blochs.B"+QubitNum), [0,0], [-1,1], [0,0], 'LineWidth', 1.5, 'Color', [0,0,0]);
            line(eval("app.blochs.B"+QubitNum), [0,0], [0,0], [-1,1], 'LineWidth', 1.5, 'Color', [0,0,0]);
        %---------------------------------------------------------------------------------------------------------------------
        end
        function KnobValueChanged2(app, event, StageNum)
        %---------------------------------------------------------------------------------------------------------------------
            statement= "app.DensityKnob.D"+StageNum+".Value;";
            NoiseFlag= eval(statement);
            if NoiseFlag == '0'
                set(eval("app.densityGraphs.DG"+StageNum),'Visible','on');
                set(eval("app.densityGraphs.DG"+StageNum+".Children"),'Visible','on');
                set(eval("app.densityColorBars.CB"+StageNum),'Visible','on');
                set(eval("app.densityNoisyGraphs.DG"+StageNum),'Visible','off');
                set(eval("app.densityNoisyGraphs.DG"+StageNum+".Children"),'Visible','off');
                set(eval("app.densityColorBars.CBN"+StageNum),'Visible','off');
            else
                set(eval("app.densityGraphs.DG"+StageNum),'Visible','off');
                set(eval("app.densityGraphs.DG"+StageNum+".Children"),'Visible','off');
                set(eval("app.densityColorBars.CB"+StageNum),'Visible','off');
                set(eval("app.densityNoisyGraphs.DG"+StageNum),'Visible','on');
                set(eval("app.densityNoisyGraphs.DG"+StageNum+".Children"),'Visible','on');
                set(eval("app.densityColorBars.CBN"+StageNum),'Visible','on');
            end
        %---------------------------------------------------------------------------------------------------------------------
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
        %------------------------------
            %If the user press Run button
            simulate(app);
        %------------------------------
        end

        % Button pushed function: BlochButton
        function BlochButtonPushed(app, event)
        %----------------------------------------------------
            % Used to export the bloch spheres for each qubit into png file
            for QubitNum= 0:(app.NumOfQubits-1)
                name= "ExportedFiles/Bloch/Bloch"+QubitNum+".png";
                exportgraphics(eval("app.blochs.B"+QubitNum),name, 'BackgroundColor', 'white', 'Resolution',300);
            end
                    %----------------------------------------------------
        end

        % Button pushed function: TablesButton
        function TablesButtonPushed(app, event)
        %--------------------------------------------------------------------------------------------------
            % Used to export the tables: (Input State, Noise Parameters, 
            % == Algorithm String & Numeric Matrix, Output state,
            % == Noisy Output State, Density Matrix, Noisy density matrix) 
            % == into csv file
            fileN= "ExportedFiles/Tables/InputStateVector"+ randi(100,1) + ".csv";
            writematrix(app.InputVector.Data, fileN); 
            fileN= "ExportedFiles/Tables/NoiseParameters"+ randi(100,1) + ".csv";
            writematrix(app.NoiseParameters.Data, fileN); 
            fileN= "ExportedFiles/Tables/AlgorithmStringMatrix"+ randi(100,1) + ".csv";
            writematrix(app.StringAlgorithmMatrix.Data, fileN); 
            fileN= "ExportedFiles/Tables/AlgorithmNumericMatrix"+ randi(100,1) + ".csv";
            writematrix(app.ComplexAlgorithmMatrix.Data, fileN);
            fileN= "ExportedFiles/Tables/OutputVector"+ randi(100,1) + ".csv";
            writematrix(app.OutputVectorT.Data, fileN); 
            fileN= "ExportedFiles/Tables/OutputDensityMatrix"+ randi(100,1) + ".csv";
            writematrix(app.DensityMatrix.Data, fileN); 
            fileN= "ExportedFiles/Tables/OutputNoisyDensityMatrix"+ randi(100,1) + ".csv";
            writematrix(app.NoisyDensityMatrix.Data, fileN); 
        %--------------------------------------------------------------------------------------------------
        end

        % Button pushed function: DensityButton
        function DensityButtonPushed(app, event)
            % Used to export the density matrices for each stage into png file
            for s= 1:(app.NumOfStages+1)
                set(eval("app.densityGraphs.DG"+s),'Visible','on');
                set(eval("app.densityGraphs.DG"+s+".Children"),'Visible','on');
                set(eval("app.densityColorBars.CB"+s),'Visible','on');

                name= "ExportedFiles/Density/Density"+s+".png";
                exportgraphics(eval("app.densityGraphs.DG"+s),name, 'BackgroundColor', 'white', 'Resolution',300);

                set(eval("app.densityGraphs.DG"+s),'Visible','off');
                set(eval("app.densityGraphs.DG"+s+".Children"),'Visible','off');
                set(eval("app.densityColorBars.CB"+s),'Visible','off');

                set(eval("app.densityNoisyGraphs.DG"+s),'Visible','on');
                set(eval("app.densityNoisyGraphs.DG"+s+".Children"),'Visible','on');
                set(eval("app.densityColorBars.CBN"+s),'Visible','on');

                name= "ExportedFiles/Density/NoisyDensity"+s+".png";
                exportgraphics(eval("app.densityNoisyGraphs.DG"+s),name, 'BackgroundColor', 'white', 'Resolution',300); 

%                 set(eval("app.densityGraphs.DG"+s),'Visible','off');
%                 set(eval("app.densityNoisyGraphs.DG"+s),'Visible','off');
%                 set(eval("app.densityGraphs.DG"+s+".Children"),'Visible','off');
%                 set(eval("app.densityNoisyGraphs.DG"+s+".Children"),'Visible','off');
%                 set(eval("app.densityColorBars.CB"+s),'Visible','off');
%                 set(eval("app.densityColorBars.CBN"+s),'Visible','off');

                myEvent.Origin = 'Value set programmatically';
                statement= "app.DensityKnob.D"+s+".ValueChangedFcn(app.DensityKnob.D"+s+","+s+")";
                eval(statement);
            end
            
        end

        % Button pushed function: DiagramsButton
        function DiagramsButtonPushed(app, event)
            exportgraphics(app.ProbDiagram,'ExportedFiles/Diagrams/ProbabilityDiagram.png', 'Resolution',300);
            exportgraphics(app.AlgorithmMatrixHeatmap,'ExportedFiles/Diagrams/AlgorithmMatrixHeatmap.png', 'BackgroundColor', 'white', 'Resolution',300);
            exportgraphics(app.AlgorithmMatrix3D,'ExportedFiles/Diagrams/AlgorithmMatrix3D.png', 'BackgroundColor', 'white', 'Resolution',300);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QCSappUIFigure = uifigure('Visible', 'off');
            app.QCSappUIFigure.Name = 'QCSapp';
            app.HTML = uihtml(app.QCSappUIFigure);
            app.HTML.HTMLSource = 'html/index.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.TabGroupVisuals = uitabgroup(app.QCSappUIFigure);
            app.ProbDiagramTab = uitab(app.TabGroupVisuals);
            app.ProbDiagramTab.Title = 'Probability Diagram';
            app.ProbDiagram = uiaxes(app.ProbDiagramTab);
            title(app.ProbDiagram, 'Propabilites')
            xlabel(app.ProbDiagram, 'Qubits')
            app.ProbDiagram.XLim = [0 1];
            app.ProbDiagram.YLim = [0 100];
            app.AlgorithmMatrixHeatmapTab = uitab(app.TabGroupVisuals);
            app.AlgorithmMatrixHeatmapTab.Title = 'Algorithm Matrix Heatmap';
            app.AlgorithmMatrix3DTab = uitab(app.TabGroupVisuals);
            app.AlgorithmMatrix3DTab.Title = 'Algorithm Matrix 3D';
            app.AlgorithmMatrix3D = uiaxes(app.AlgorithmMatrix3DTab);
            title(app.AlgorithmMatrix3D, 'Title')
            xlabel(app.AlgorithmMatrix3D, 'X')
            ylabel(app.AlgorithmMatrix3D, 'Y')
            zlabel(app.AlgorithmMatrix3D, 'Z')
            app.BlochTracerTabGroup = uitabgroup(app.QCSappUIFigure);
            app.BlochsTab = uitab(app.BlochTracerTabGroup);
            app.BlochsTab.Title = 'Bloch';
            app.UIAxes_2 = uiaxes(app.BlochsTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.BlochsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroupTables = uitabgroup(app.QCSappUIFigure);
            app.InputVectorTab = uitab(app.TabGroupTables);
            app.InputVectorTab.Title = 'Input Vector';
            app.InputVector = uitable(app.InputVectorTab);
            app.InputVector.ColumnName = '';
            app.InputVector.RowName = {'x,y,t'};
            app.NoiseParametersTab = uitab(app.TabGroupTables);
            app.NoiseParametersTab.Title = 'Noise Parameters';
            app.NoiseParameters = uitable(app.NoiseParametersTab);
            app.NoiseParameters.ColumnName = '';
            app.NoiseParameters.RowName = {'x,y,t'};
            app.StringAlgorithmMatrixTab = uitab(app.TabGroupTables);
            app.StringAlgorithmMatrixTab.Title = 'Algorithm Matrix';
            app.StringAlgorithmMatrix = uitable(app.StringAlgorithmMatrixTab);
            app.StringAlgorithmMatrix.ColumnName = '';
            app.StringAlgorithmMatrix.RowName = {};
            app.ComplexAlgorithmMatrixTab = uitab(app.TabGroupTables);
            app.ComplexAlgorithmMatrixTab.Title = 'Numeric Algorithm Matrix';
            app.ComplexAlgorithmMatrix = uitable(app.ComplexAlgorithmMatrixTab);
            app.ComplexAlgorithmMatrix.ColumnName = '';
            app.ComplexAlgorithmMatrix.RowName = {};
            app.OutputVectorTab = uitab(app.TabGroupTables);
            app.OutputVectorTab.Title = 'Output Vector';
            app.OutputVectorT = uitable(app.OutputVectorTab);
            app.OutputVectorT.ColumnName = '';
            app.OutputVectorT.RowName = {};
            app.DensityMatrixTab = uitab(app.TabGroupTables);
            app.DensityMatrixTab.Title = 'Density Matrix';
            app.DensityMatrix = uitable(app.DensityMatrixTab);
            app.DensityMatrix.ColumnName = '';
            app.DensityMatrix.RowName = {};
            app.NoisyDensityMatrixTab = uitab(app.TabGroupTables);
            app.NoisyDensityMatrixTab.Title = 'Noisy Density Matrix';
            app.NoisyDensityMatrix = uitable(app.NoisyDensityMatrixTab);
            app.NoisyDensityMatrix.ColumnName = '';
            app.NoisyDensityMatrix.RowName = {};
            app.BlochButton = uibutton(app.QCSappUIFigure, 'push');
            app.BlochButton.ButtonPushedFcn = createCallbackFcn(app, @BlochButtonPushed, true);
            app.BlochButton.Text = 'Bloch';
            app.TablesButton = uibutton(app.QCSappUIFigure, 'push');
            app.TablesButton.ButtonPushedFcn = createCallbackFcn(app, @TablesButtonPushed, true);
            app.TablesButton.Text = 'Tables';
            app.DensityTracerTabGroup = uitabgroup(app.QCSappUIFigure);
            app.DensityTab = uitab(app.DensityTracerTabGroup);
            app.DensityTab.Title = 'Density';
            app.UIAxes2 = uiaxes(app.DensityTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.DensityButton = uibutton(app.QCSappUIFigure, 'push');
            app.DensityButton.ButtonPushedFcn = createCallbackFcn(app, @DensityButtonPushed, true);
            app.DensityButton.Text = 'Density';
            app.DiagramsButton = uibutton(app.QCSappUIFigure, 'push');
            app.DiagramsButton.ButtonPushedFcn = createCallbackFcn(app, @DiagramsButtonPushed, true);
            app.DiagramsButton.Text = 'Diagrams';
            % Show the figure after all components are created
            app.QCSappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Psitrum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QCSappUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QCSappUIFigure)
        end
    end
end